classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ServoSpeedKnob          matlab.ui.control.Knob
        ServoSpeedKnobLabel     matlab.ui.control.Label
        ConnectButton           matlab.ui.control.Button
        ConnectLamp             matlab.ui.control.Lamp
        ConnectLampLabel        matlab.ui.control.Label
        ServoSwitch             matlab.ui.control.Switch
        ServoSwitchLabel        matlab.ui.control.Label
        ServoPinEditField       matlab.ui.control.EditField
        ServoPinEditFieldLabel  matlab.ui.control.Label
        COMPortEditField        matlab.ui.control.EditField
        COMPortEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = public)
        a;          % Arduino object
        s;          % Servo object 
        serial;     % Serial Port 
        ledPin;     % LED Pin 
        servoPin;   % Servo motor Pin 
        
        startReadingData = false; % boolean to checks if it has been instructed to start reading from the photodetector   % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            % Once the connection is no longer needed, clear the arduino object.
            clear app.a app.s; 
        end

        % Value changed function: COMPortEditField
        function COMPortEditFieldValueChanged(app, event)
            value = app.COMPortEditField.Value;
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % Read inputs from edit box 
            app.serial = app.COMPortEditField.Value; 
            app.ledPin = app.LEDPinEditField.Value;
            app.servoPin = app.ServoPinEditField.Value; 

            % Establish connection 
            try
                % create arduino object 
                app.a = arduino(app.serial,'Uno', 'Libraries', 'Servo');
                % create servo object 
                app.s = servo(app.a, app.servoPin);
                writePosition(app.s, 0);
                app.ConnectedLamp.Color = 'Green'; 
            catch ME
                errordlg(ME.message, 'Unable to connect to target');
            end 
        end

        % Value changed function: ServoSwitch
        function ServoSwitchValueChanged(app, event)
            value = app.ServoSwitch.Value;  % Get the value of the servo switch (not used in this loop currently)
            n = 0;
            step = 0;
            displacements = 10;  % Number of total oscillations
            stepSize = 1;        % The size of each step, in degrees
            speedControl = app.ServoSpeedKnob.Value;  % Dynamic speed control from the knob
            
            while (n < displacements/2)
                % Sweep from 0 to 180 degrees
                while (step < 180)
                    writePosition(app.s, step/180);  % Normalized position (0 to 1 for 0° to 180°)
                    pause(0.1 - speedControl/1000);  % Dynamic pause based on speed control
                    step = step + stepSize;
                end
            
                % Sweep back from 180 to 0 degrees
                while (step > 0)
                    writePosition(app.s, step/180);  % Normalized position (0 to 1 for 0° to 180°)
                    pause(0.1 - speedControl/1000);  % Dynamic pause based on speed control
                    step = step - stepSize;
                end
                n = n + 1;
            end
         
        end

        % Callback function: not associated with a component
        function ServoSpeedKnobValueChanging(app, event)
  
        end

        % Callback function: not associated with a component
        function ServoSpeedKnobValueChanging2(app, event)
            changingValue = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.COMPortEditFieldLabel = uilabel(app.UIFigure);
            app.COMPortEditFieldLabel.Text = 'COM Port';
            app.COMPortEditField = uieditfield(app.UIFigure, 'text');
            app.COMPortEditField.ValueChangedFcn = createCallbackFcn(app, @COMPortEditFieldValueChanged, true);
            app.ServoPinEditFieldLabel = uilabel(app.UIFigure);
            app.ServoPinEditFieldLabel.Text = 'Servo Pin';
            app.ServoPinEditField = uieditfield(app.UIFigure, 'text');
            app.ServoSwitchLabel = uilabel(app.UIFigure);
            app.ServoSwitchLabel.Text = 'Servo';
            app.ServoSwitch = uiswitch(app.UIFigure, 'slider');
            app.ServoSwitch.ValueChangedFcn = createCallbackFcn(app, @ServoSwitchValueChanged, true);
            app.ConnectLampLabel = uilabel(app.UIFigure);
            app.ConnectLampLabel.Text = 'Connect';
            app.ConnectLamp = uilamp(app.UIFigure);
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ServoSpeedKnobLabel = uilabel(app.UIFigure);
            app.ServoSpeedKnobLabel.Text = 'Servo Speed Knob';
            app.ServoSpeedKnob = uiknob(app.UIFigure, 'continuous');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end